classdef BiaxialControlSystemTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofDeviationsEditField    matlab.ui.control.NumericEditField
        NumberofDeviationsEditFieldLabel  matlab.ui.control.Label
        PercentageDifferenceEditField  matlab.ui.control.NumericEditField
        PercentageDifferenceEditFieldLabel  matlab.ui.control.Label
        DatapointsEditField            matlab.ui.control.NumericEditField
        FrequencyLabel                 matlab.ui.control.Label
        LoadUnitsDropDown              matlab.ui.control.DropDown
        LoadUnitsDropDownLabel         matlab.ui.control.Label
        AdjustmentSpeedEditFieldLabel  matlab.ui.control.Label
        AdjustmentSpeedEditField       matlab.ui.control.NumericEditField
        ReturnToHomeButton             matlab.ui.control.Button
        yPositive                      matlab.ui.control.StateButton
        yNegative                      matlab.ui.control.StateButton
        xPositive                      matlab.ui.control.StateButton
        xNegative                      matlab.ui.control.StateButton
        XLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XStrainRate                    matlab.ui.control.NumericEditField
        YStrainRate                    matlab.ui.control.NumericEditField
        YTORC                          matlab.ui.control.DropDown
        XTORC                          matlab.ui.control.DropDown
        ModeSelection                  matlab.ui.control.DropDown
        StrainrateLabelX               matlab.ui.control.Label
        StrainrateLabelY               matlab.ui.control.Label
        StartButton                    matlab.ui.control.StateButton
        SetHomePositionButton          matlab.ui.control.Button
        DataCollectionLabel            matlab.ui.control.Label
        DeviationDetectionLabel        matlab.ui.control.Label
    end

% Toronot Metroploital Univeristy - Aerospace Engineering Department
% Project: Biaxial Testing Apparatus Control System
% Created by: Andrew Colledge
%
% The following code is used to control the stepper motors and load cell of the biaxial testing apparatus.
% This code works in conjunction with a required Arduino IDE code.
% Make sure Arduino code is uploaded to the Arduino's on board storage
% before starting the UI
%
% August 2023    
    
    properties (Access = private)
        Arduino = []; % closes serial port
        loadX = [0;0];
        loadY = [0;0];
        displacmentX = [0;0];
        displacmentY = [0;0];
        i = 1;
        time = [0;0];
        error = 0;
        fileNum = 1;
        count = 0;
        leadScrew = 8.0; %load screw pitch in mm
        Timer % frecuency timer for data points
    end
    
    methods (Access = private)
            
        function readData(app,~,~)
            disp("data!!!!!!!!!");
            getData(app);
            %loadDeviation(app,app.loadX);
            %loadDeviation(app,app.loadY);
            app.i = app.i + 1;
        end
        
        function loadDeviation(app,loadValue)
            if app.i >= 3
                current = loadValue(app.i);
                previous = loadValue(app.i-1);
                pDif=100*((current - previous)/previous);
                %if over pDif then add to counter
                if abs(pDif) >= app.PercentageDifferenceEditField.Value
                    app.error = app.error + 1;
                end
                %Stall prevention function is pDif is constantly zero
                if pDif == 0
                    app.count = app.count + 1;
                else
                    app.count = 0;
                end
                if app.count == 5 %count=5 then stop
                    stop(app.Timer);
                    compileData(app);
                end
                %if errors equals user set value then stop
                if app.error == app.NumberofDeviationsEditField.Value
                    stop(app.Timer);
                    compileData(app);
                end
            end
        end
        
        function getData(app)
            %send data collection command to Arduino 
            writeline(app.Arduino,"data");
            %Get X and Y Load data -------------------------------------------------
            x = readline(app.Arduino); %get X Load
            x = str2double(x);
            y = readline(app.Arduino); %get Y Load
            y = str2double(y);
            switch app.LoadUnitsDropDown.Value
                case 'Kilograms'
                    %convert to Kg
                    x = (x * -3.758);
                    app.loadX(app.i) = x;
                    y = (y * -3.758);
                    app.loadY(app.i) = y;
                 case 'Pounds'
                    %convert to lbs
                    x = (x * -8.287);
                    app.loadX(app.i) = x;
                    y = (y * -8.287);
                    app.loadY(app.i) = y;
            end
                
            %Get displacement data--------------------------------------
            dispX = readline(app.Arduino); %get X displacement
            dispX = str2double(dispX);
            dispX = dispX/(80000/app.leadScrew); %convert displacement from steps to mm.
            app.displacmentX(app.i) = dispX;
            dispY = readline(app.Arduino); %get Y displacement
            dispY = str2double(dispY);
            dispY = dispY/(80000/app.leadScrew);%convert displacement from steps to mm.
            app.displacmentY(app.i) = dispY;
            app.time(app.i) = toc;
        end
        
        function compileData(app)
            switch app.LoadUnitsDropDown.Value
                    case 'Kilograms'
                        labelX = 'Load X (kg)';
                        labelY = 'Load y (kg)';
                    case 'Pounds'
                        labelX = 'Load X (lbs)';
                        labelY = 'Load y (lbs)';
            end
            %create Excel file
            data = table(app.time,app.displacmentX,app.displacmentY,app.loadX,app.loadY,'VariableNames', {'Time (s)','X Displacment (mm)','Y Displacment (mm)', labelX, labelY,});
            filename = sprintf('table%02d.xls',app.fileNum);
            writetable(data,filename,'FileType',"spreadsheet");
            app.fileNum = app.fileNum + 1;
        end

        function adjustStepper(app)
            press = false;
            %when adjustment button is pressed move stepper at set speed
            if (app.xNegative.Value == 1); motor = "XN"; press = true;end
            if (app.yNegative.Value == 1); motor = "YN"; press = true;end
            if (app.xPositive.Value == 1); motor = "XP"; press = true;end
            if (app.yPositive.Value == 1); motor = "YP"; press = true;end
            
            %when adjustment button is unpressed stop stepper
            if (app.xNegative.Value == 0); writeline(app.Arduino,"STOP!");end
            if (app.yNegative.Value == 0); writeline(app.Arduino,"STOP!");end
            if (app.xPositive.Value == 0); writeline(app.Arduino,"STOP!");end
            if (app.yPositive.Value == 0); writeline(app.Arduino,"STOP!");end
            
            if press == true
                adjSpeed = app.AdjustmentSpeedEditField.Value/60*(80000/app.leadScrew)/2;%change speed from mm/min to steps/second
                writeline(app.Arduino,motor);
                formateSpec = "%.3f";
                adjSpeedMessage = compose(formateSpec,adjSpeed); %create string
                writeline(app.Arduino,adjSpeedMessage);
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %UI Visibility on startup
            app.XLabel.Visible = 'on';
            app.YLabel.Visible = 'on';
            app.XStrainRate.Visible = 'off';
            app.XStrainRate.Value = 0;
            app.YStrainRate.Visible = 'off';
            app.YStrainRate.Value = 0;
            app.XTORC.Visible = 'off';
            app.YTORC.Visible = 'off';
            app.xPositive.Visible = 'on';
            app.yPositive.Visible = 'on';
            app.xNegative.Visible = 'on';
            app.yNegative.Visible = 'on';
            app.SetHomePositionButton.Visible = 'on';
            app.StartButton.Visible = "off";
            app.ReturnToHomeButton.Visible = "on";
            app.AdjustmentSpeedEditField.Visible = "on";
            app.AdjustmentSpeedEditFieldLabel.Visible = 'on';
            app.StrainrateLabelX.Visible = 'off';
            app.StrainrateLabelY.Visible = 'off';
            
            %open serial port with Arduino
            delete(instrfind({'Port'},{'COM3'}));
            app.Arduino = serialport("COM3",9600);
            pause(2); %pause so MATLAB can establish comunication with Arduino 
        end

        % Value changed function: ModeSelection
        function ModeSelectionValueChanged(app, event)
            switch app.ModeSelection.Value %Turns Ui elements on or off depending on which testing mode is being used
                case 'Run Test'
                    app.XLabel.Visible = 'on';
                    app.YLabel.Visible = 'on';
                    app.XStrainRate.Visible = 'on';
                    app.YStrainRate.Visible = 'on';
                    app.XTORC.Visible = 'on';
                    app.YTORC.Visible = 'on';
                    app.xPositive.Visible = 'off';
                    app.yPositive.Visible = 'off';
                    app.xNegative.Visible = 'off';
                    app.yNegative.Visible = 'off';
                    app.SetHomePositionButton.Visible = 'off';
                    app.StartButton.Visible = "on";
                    app.ReturnToHomeButton.Visible = "on";
                    app.AdjustmentSpeedEditField.Visible = "off";
                    app.AdjustmentSpeedEditFieldLabel.Visible = 'off';
                    app.StrainrateLabelX.Visible = 'on';
                    app.StrainrateLabelY.Visible = 'on';
                case 'Adjust Motors'
                    app.XLabel.Visible = 'on';
                    app.YLabel.Visible = 'on';
                    app.XStrainRate.Visible = 'off';
                    app.YStrainRate.Visible = 'off';
                    app.XTORC.Visible = 'off';
                    app.YTORC.Visible = 'off';
                    app.xPositive.Visible = 'on';
                    app.yPositive.Visible = 'on';
                    app.xNegative.Visible = 'on';
                    app.yNegative.Visible = 'on';
                    app.SetHomePositionButton.Visible = 'on';
                    app.StartButton.Visible = "off";
                    app.ReturnToHomeButton.Visible = "on";
                    app.AdjustmentSpeedEditField.Visible = "on";
                    app.AdjustmentSpeedEditFieldLabel.Visible = 'on';
                    app.StrainrateLabelX.Visible = 'off';
                    app.StrainrateLabelY.Visible = 'off';
            end 
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            %Start Button Gets pushed
            if app.StartButton.Value == 1
                %change UI elements
                app.StartButton.BackgroundColor = 'r';
                app.StartButton.Text = 'STOP';
                app.ReturnToHomeButton.Visible = 0;
                tic;
                %Get Y rotation direction
                switch app.YTORC.Value
                    case 'Tension'
                        YDirection = 1;
                    case 'Compression'
                        YDirection = 0;
                end
                %Get X rotation direction
                switch app.XTORC.Value
                    case 'Tension'
                        XDirection = 1;
                    case 'Compression'
                        XDirection = 0;
                end
                %adjust speed values from mm/min to steps/second
                xSpeed = app.XStrainRate.Value/60*(80000/app.leadScrew)/2;
                ySpeed = app.YStrainRate.Value/60*(80000/app.leadScrew)/2;
                %create timer function
                freq = app.DatapointsEditField.Value;%Get user set frequency
                app.Timer = timer(...
                    'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                    'Period', freq, ...                  % Period is set by user
                    'BusyMode', 'queue',...              % Queue timer callbacks when busy
                    'TimerFcn', @app.readData);          % Specify callback function
                %Build string and send to Arduino
                Arr = [XDirection xSpeed YDirection ySpeed];
                formateSpec = "%d,%.3f,%d,%.3f";
                Message = compose(formateSpec,Arr);
                writeline(app.Arduino,Message);
                %start data collection with timer
                start(app.Timer);
            end
            %Stop Button Gets pushed
            if app.StartButton.Value == 0
                stop(app.Timer);%Stop timer
                writeline(app.Arduino,"STOP!"); %sends stop command to Arduino
                compileData(app); 
                %clear arrays for subsuquent tests
                app.loadX = [0;0];
                app.loadY = [0;0];
                app.displacmentX = [0;0];
                app.displacmentY = [0;0];
                app.i = 1;
                app.time = [0;0];
                %change UI elements
                app.StartButton.BackgroundColor = 'g';
                app.StartButton.Text = 'START';
                app.ReturnToHomeButton.Visible = 1;
            end 
        end

        % Button pushed function: ReturnToHomeButton
        function ReturnToHomeButtonPushed(app, event)
            writeline(app.Arduino,"RETURN");%sends "return to home" command
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Arduino = [];
            %if strcmp(app.Timer.Running, 'on')
            %    stop(app.Timer);
            %end
            delete(app.Timer);
            delete(app);
            close all force;
        end

        % Button pushed function: SetHomePositionButton
        function SetHomePositionButtonPushed(app, event)
            writeline(app.Arduino,"sethome") %sends "sethome" command
        end

        % Value changed function: xNegative
        function xNegativeValueChanged(app, event)
            app.adjustStepper();
        end

        % Value changed function: xPositive
        function xPositiveValueChanged(app, event)
            app.adjustStepper();
        end

        % Value changed function: yPositive
        function yPositiveValueChanged(app, event)
            app.adjustStepper();
        end

        % Value changed function: yNegative
        function yNegativeValueChanged(app, event)
            app.adjustStepper(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DeviationDetectionLabel = uilabel(app.UIFigure);
            app.DeviationDetectionLabel.Text = 'Deviation Detection';
            app.DataCollectionLabel = uilabel(app.UIFigure);
            app.DataCollectionLabel.Text = 'Data Collection';
            app.SetHomePositionButton = uibutton(app.UIFigure, 'push');
            app.SetHomePositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetHomePositionButtonPushed, true);
            app.SetHomePositionButton.Text = 'Set Home Position';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StrainrateLabelY = uilabel(app.UIFigure);
            app.StrainrateLabelY.Text = 'Displacement Speed';
            app.StrainrateLabelX = uilabel(app.UIFigure);
            app.StrainrateLabelX.Text = 'Displacement Speed';
            app.ModeSelection = uidropdown(app.UIFigure);
            app.ModeSelection.Items = {'Adjust Motors', 'Run Test'};
            app.ModeSelection.ValueChangedFcn = createCallbackFcn(app, @ModeSelectionValueChanged, true);
            app.ModeSelection.Value = 'Adjust Motors';
            app.XTORC = uidropdown(app.UIFigure);
            app.XTORC.Items = {'Tension', 'Compression'};
            app.XTORC.Value = 'Tension';
            app.YTORC = uidropdown(app.UIFigure);
            app.YTORC.Items = {'Tension', 'Compression'};
            app.YTORC.Value = 'Tension';
            app.YStrainRate = uieditfield(app.UIFigure, 'numeric');
            app.YStrainRate.Limits = [0 Inf];
            app.YStrainRate.Tooltip = {'Strainrate in mm/min'};
            app.XStrainRate = uieditfield(app.UIFigure, 'numeric');
            app.XStrainRate.Limits = [0 Inf];
            app.XStrainRate.Tooltip = {'Strainrate in mm/min'};
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.xNegative = uibutton(app.UIFigure, 'state');
            app.xNegative.ValueChangedFcn = createCallbackFcn(app, @xNegativeValueChanged, true);
            app.xNegative.Text = '-';
            app.xPositive = uibutton(app.UIFigure, 'state');
            app.xPositive.ValueChangedFcn = createCallbackFcn(app, @xPositiveValueChanged, true);
            app.xPositive.Text = '+';
            app.yNegative = uibutton(app.UIFigure, 'state');
            app.yNegative.ValueChangedFcn = createCallbackFcn(app, @yNegativeValueChanged, true);
            app.yNegative.Text = '-';
            app.yPositive = uibutton(app.UIFigure, 'state');
            app.yPositive.ValueChangedFcn = createCallbackFcn(app, @yPositiveValueChanged, true);
            app.yPositive.Text = '+';
            app.ReturnToHomeButton = uibutton(app.UIFigure, 'push');
            app.ReturnToHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnToHomeButtonPushed, true);
            app.ReturnToHomeButton.Text = 'Return To Home';
            app.AdjustmentSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustmentSpeedEditField.Limits = [0 Inf];
            app.AdjustmentSpeedEditField.Value = 20;
            app.AdjustmentSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustmentSpeedEditFieldLabel.Text = 'Adjustment Speed';
            app.LoadUnitsDropDownLabel = uilabel(app.UIFigure);
            app.LoadUnitsDropDownLabel.Text = 'Load Units';
            app.LoadUnitsDropDown = uidropdown(app.UIFigure);
            app.LoadUnitsDropDown.Items = {'Kilograms', 'Pounds'};
            app.LoadUnitsDropDown.Value = 'Kilograms';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.DatapointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatapointsEditField.Limits = [0.01 Inf];
            app.DatapointsEditField.Tooltip = {'The rate of data collection in seconds. (high frequency will affects stepper motors)'};
            app.DatapointsEditField.Value = 5;
            app.PercentageDifferenceEditFieldLabel = uilabel(app.UIFigure);
            app.PercentageDifferenceEditFieldLabel.Text = 'Percentage Difference';
            app.PercentageDifferenceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentageDifferenceEditField.Tooltip = {'The percentage difference needed to trigger deviation detection function.'};
            app.PercentageDifferenceEditField.Value = 50;
            app.NumberofDeviationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDeviationsEditFieldLabel.Text = 'Number of Deviations';
            app.NumberofDeviationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDeviationsEditField.Limits = [1 Inf];
            app.NumberofDeviationsEditField.Tooltip = {'The number of load cell deviations before automatically stoping the test.'};
            app.NumberofDeviationsEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BiaxialControlSystemTime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end